classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Button                        matlab.ui.control.Button
        FilenameEditFieldLabel        matlab.ui.control.Label
        FilenameEditField             matlab.ui.control.EditField
        SplitNumber050EditFieldLabel  matlab.ui.control.Label
        NumberEditField               matlab.ui.control.NumericEditField
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            fn = app.FilenameEditField.Value;
            t = app.NumberEditField.Value;
            if isfile(fn)
                 T = readtable(fn);
            else
                 uialert(app.UIFigure,'File not found','Invalid File');
                 return
            end
            dat = double(T.Dates);
            bas = double(T.Bases);
            chn = double(T.Changes);
            if (t > size(dat, 1) || t > size(bas, 1) || t > size(chn, 1))
                uialert(app.UIFigure,'Split number is too big', 'Wrong split number');
                return
            end
            diffs = zeros(size(dat,1) - t, 1);
            for i = t + 1:size(dat, 1)
                cur = chn(i);
                for j = 1:i
                    if (cur < bas(j))
                        diffs(i - t) = i - j + 1;
                        break
                    end
                end
            end
            disp(dat(t + 1:end)');
            disp(diffs');
            plot(app.UIAxes, dat(t + 1:end), diffs);
            T.Differs = [zeros(t, 1); diffs];
            writetable(T, fn);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Рассчет';
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.FilenameEditField.Value = 'datafile.xlsx';
            app.SplitNumber050EditFieldLabel = uilabel(app.UIFigure);
            app.SplitNumber050EditFieldLabel.Text = 'Split Number (0 - 50)';
            app.NumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberEditField.Limits = [0 50];
            app.NumberEditField.Value = 10;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Динамика')
            xlabel(app.UIAxes, 'Значение')
            ylabel(app.UIAxes, 'Динамика')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end