classdef task2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        Panel       matlab.ui.container.Panel
        CheckBox    matlab.ui.control.CheckBox
        CheckBox_2  matlab.ui.control.CheckBox
        Button      matlab.ui.control.Button
        UIAxes      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            cb1 = app.CheckBox.Value;
            cb2 = app.CheckBox_2.Value;
            x = 0:0.2:10;
            s = sin(x);
            c = cos(x);
            if cb1 && cb2
                hold(app.UIAxes, 'off');
                plot(app.UIAxes,x,s);
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,x,c);
            else if cb1
                hold(app.UIAxes, 'off');
                plot(app.UIAxes,x,s);
            else if cb2
                hold(app.UIAxes, 'off');
                plot(app.UIAxes,x,c);
            else
                hold(app.UIAxes, 'off');
                plot(app.UIAxes,0,0);
                end 
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Графики';
            app.CheckBox = uicheckbox(app.Panel);
            app.CheckBox.Text = 'Синус';
            app.CheckBox_2 = uicheckbox(app.Panel);
            app.CheckBox_2.Text = 'Косинус';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Рассичтать';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 10];
            app.UIAxes.YLim = [-1 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = task2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end