classdef task3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        ex2Panel   matlab.ui.container.Panel
        Button_2   matlab.ui.control.Button
        Button     matlab.ui.control.Button
        sinxPanel  matlab.ui.container.Panel
        Button_3   matlab.ui.control.Button
        Button_4   matlab.ui.control.Button
        UIAxes     matlab.ui.control.UIAxes
        UIAxes_2   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            hold(app.UIAxes, "off");
            plot(app.UIAxes, 0, 0);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            x = 0:0.2:4*pi;
            s = sin(x);
            plot(app.UIAxes_2, x, s);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            hold(app.UIAxes_2, "off");
            plot(app.UIAxes_2, 0, 0);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            x = -2:0.2:2;
            e = exp(-(x.^2));
            plot(app.UIAxes, x, e);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ex2Panel = uipanel(app.UIFigure);
            app.ex2Panel.Title = 'e^(-x^2)';
            app.Button_2 = uibutton(app.ex2Panel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = 'Очистить';
            app.Button = uibutton(app.ex2Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Построить';
            app.sinxPanel = uipanel(app.UIFigure);
            app.sinxPanel.Title = 'sin x';
            app.Button_3 = uibutton(app.sinxPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = 'Построить';
            app.Button_4 = uibutton(app.sinxPanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = 'Очистить';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'e^{-x^2}')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-2 2];
            app.UIAxes.YLim = [0 1];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'sinx')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XLim = [0 12.5663706143592];
            app.UIAxes_2.YLim = [-1 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = task3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end